classdef GaussLaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GaussLabUIFigure            matlab.ui.Figure
        SelectCaseMenu              matlab.ui.container.Menu
        RandomMenu                  matlab.ui.container.Menu
        PointChargeMenu             matlab.ui.container.Menu
        InfiniteLineMenu            matlab.ui.container.Menu
        InfinitePlaneMenu           matlab.ui.container.Menu
        SolidSphereMenu             matlab.ui.container.Menu
        SphericalShellMenu          matlab.ui.container.Menu
        Step1Quest                  matlab.ui.container.Panel
        Step1QuestLayout            matlab.ui.container.GridLayout
        Step1Label                  matlab.ui.control.Label
        Step2Quest                  matlab.ui.container.Panel
        Step2QuestLayout            matlab.ui.container.GridLayout
        Step2Question               matlab.ui.control.Label
        Step3Quest                  matlab.ui.container.Panel
        Step3QuestLayout            matlab.ui.container.GridLayout
        Step3Label                  matlab.ui.control.Label
        Step4Quest                  matlab.ui.container.Panel
        Step4QuestLayout            matlab.ui.container.GridLayout
        Step4Label1                 matlab.ui.control.Label
        Step5Quest                  matlab.ui.container.Panel
        Step5QuestLayout            matlab.ui.container.GridLayout
        Step5Label                  matlab.ui.control.Label
        Step6Quest                  matlab.ui.container.Panel
        Step6QuestLayout            matlab.ui.container.GridLayout
        Step6Label                  matlab.ui.control.Label
        Step1Ctrl                   matlab.ui.container.Panel
        Step1CtrlLayout             matlab.ui.container.GridLayout
        CheckBoxSpherical           matlab.ui.control.CheckBox
        CheckBoxCylindrical         matlab.ui.control.CheckBox
        CheckBoxPlanar              matlab.ui.control.CheckBox
        CheckBoxNone                matlab.ui.control.CheckBox
        Step1Label1                 matlab.ui.control.Label
        Step1Label2                 matlab.ui.control.Label
        Step1Label3                 matlab.ui.control.Label
        Step1Label4                 matlab.ui.control.Label
        Step2Ctrl                   matlab.ui.container.Panel
        Step2CtrlLayout             matlab.ui.container.GridLayout
        Step2Check1                 matlab.ui.control.CheckBox
        Step2Check4                 matlab.ui.control.CheckBox
        Step2Check3                 matlab.ui.control.CheckBox
        Step2Check2                 matlab.ui.control.CheckBox
        Step2Label1                 matlab.ui.control.Label
        Step2Label2                 matlab.ui.control.Label
        Step2Label3                 matlab.ui.control.Label
        Step2Label4                 matlab.ui.control.Label
        Step3Ctrl                   matlab.ui.container.Panel
        Step3CtrlLayout             matlab.ui.container.GridLayout
        SurfaceDropDown             matlab.ui.control.DropDown
        SurfaceLabel                matlab.ui.control.Label
        Step4Ctrl                   matlab.ui.container.Panel
        Step4CtrlLayout             matlab.ui.container.GridLayout
        Step4Sym                    matlab.ui.control.Label
        TotalchargeLabel            matlab.ui.control.Label
        Step4Answer                 matlab.ui.control.EditField
        Step5Ctrl                   matlab.ui.container.Panel
        Step5CtrlLayout             matlab.ui.container.GridLayout
        Step5Check1                 matlab.ui.control.CheckBox
        Step5Check2                 matlab.ui.control.CheckBox
        Step5Check3                 matlab.ui.control.CheckBox
        Step5Check4                 matlab.ui.control.CheckBox
        Step5Label1                 matlab.ui.control.Label
        Step5Label2                 matlab.ui.control.Label
        Step5Label3                 matlab.ui.control.Label
        Step5Label4                 matlab.ui.control.Label
        Step6Ctrl                   matlab.ui.container.Panel
        Step6CtrlLayout             matlab.ui.container.GridLayout
        Step6Check1                 matlab.ui.control.CheckBox
        Step6Check2                 matlab.ui.control.CheckBox
        Step6Check3                 matlab.ui.control.CheckBox
        Step6Check4                 matlab.ui.control.CheckBox
        Step6Label1                 matlab.ui.control.Label
        Step6Label2                 matlab.ui.control.Label
        Step6Label3                 matlab.ui.control.Label
        Step6Label4                 matlab.ui.control.Label
        GeneralArea                 matlab.ui.container.GridLayout
        WelcomeLabel                matlab.ui.control.Label
        ClearButton                 matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        PreviousButton              matlab.ui.control.Button
        DescriptionDistribution     matlab.ui.control.Label
        DescriptionGaussianSurface  matlab.ui.control.Label
        QuestPanel                  matlab.ui.container.Panel
        CtrlPanel                   matlab.ui.container.Panel
        FeedbackPanel               matlab.ui.container.Panel
        FeedbackLayout              matlab.ui.container.GridLayout
        FeedbackLabel               matlab.ui.control.Label
        CheckAnswerButton           matlab.ui.control.Button
        Axes                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        Case = "";  % ["Point Charge", "Infinite Line", "Infinite Plane", "Solid Sphere", "Spherical Shell"]
        Step = 0; % [0 1 2 3 4 5 6]
        Status = zeros(1,6); % [0 0 0 0 0 0]
        DistribPlot = [];
        SurfacePlot = [];
        Feedback = ["","","","","",""];
        GaussChoice = string([]);
        GaussAnnotation = "";
    end

    methods (Access = private)

        function Update(app)
            UpdatePanel(app);
            UpdatePlot(app);
        end

        function UpdatePlot(app)

            if isempty(app.DistribPlot)
                delete(app.Axes.Children)
                view(app.Axes,3);
                axis(app.Axes,"equal")
                switch (app.Case)
                    case "Point Charge"
                        app.DistribPlot = scatter3(app.Axes,0,0,0,25,"filled",SeriesIndex=1);
                        app.DescriptionDistribution.Text = "\bf Discrete charged located at (0,0,0)";
                        app.DescriptionDistribution.Text = app.DescriptionDistribution.Text + "\"+...
                            "   with charge $q$ in C";
                    case "Infinite Line"
                        app.DistribPlot = xline(app.Axes,0,SeriesIndex=1,LineWidth=3,Alpha=1);
                        app.DescriptionDistribution.Text = "\bf Infinite charged line passing by (0,0,0) and a normal vector $\vec{n} = (1,0,0)$";
                        app.DescriptionDistribution.Text = app.DescriptionDistribution.Text + "\"+...
                            "   with a charge density of $\lambda$ in C/m";
                    case "Infinite Plane"
                        app.DistribPlot = constantplane(app.Axes,"z",0,SeriesIndex=1,FaceAlpha=0.8);
                        app.DescriptionDistribution.Text = "\bf Infinite charged plane passing by (0,0,0) and a normal vector $\vec{n} = (0,0,1)$";
                        app.DescriptionDistribution.Text = app.DescriptionDistribution.Text + "\"+...
                            "   with a charge density of $\sigma$ in C/m$^2$";
                    case "Solid Sphere"
                        [x,y,z] = sphere(40);
                        app.DistribPlot = surf(app.Axes,x,y,z,LineStyle="none");
                        app.DescriptionDistribution.Text = "\bf Filled charged sphere centered on (0,0,0) with a radius of $R_{s}$";
                        app.DescriptionDistribution.Text = app.DescriptionDistribution.Text + "\"+...
                            "   with a charge density of $\rho$ in C/m$^3$";
                    case "Spherical Shell"
                        [x,y,z] = sphere(40);
                        app.DistribPlot = surf(app.Axes,x,y,z,LineStyle="none");
                        app.DescriptionDistribution.Text = "\bf Charged spherical shell centered on (0,0,0) with a radius of $R_{s}$";
                        app.DescriptionDistribution.Text = app.DescriptionDistribution.Text + "\"+...
                            "   with a charge density of $\sigma$ in C/m$^2$";
                    otherwise
                        error("This case is not supported yet.")
                end
                xlim(app.Axes,[-5,5])
                ylim(app.Axes,[-5,5])
                zlim(app.Axes,[-5,5])
                zoom(app.Axes,0.5);
                app.Axes.Visible = "on";
            end

            % Make sure right Gaussian surface is selected if passed step 3
            if app.Step > 3
                switch app.Case
                    case "Point Charge"
                        % Spherical symmetry
                        app.GaussChoice = "Gaussian Sphere";
                    case "Infinite Line"
                        % Cylindrical symmetry
                        app.GaussChoice = "Gaussian Cylinder";
                    case "Infinite Plane"
                        % Planar symmetry
                        app.GaussChoice = "Gaussian Cube";
                    case "Solid Sphere"
                        % Spherical symmetry
                        app.GaussChoice = "Gaussian Sphere";
                    case "Spherical Shell"
                        % Spherical symmetry
                        app.GaussChoice = "Gaussian Sphere";
                end
            end


            % Define and plot Gauss surface
            app.DescriptionGaussianSurface.Text = "";
            if ~isempty(app.GaussChoice)
                switch (app.GaussChoice)
                    case "Gaussian Cube"
                        gm = multicuboid(3,3,3,Zoffset=-1.5);
                        app.DescriptionGaussianSurface.Text = "\bf{Gaussian Surface:}"+newline+" Cube with side length $L_g$";
                    case "Gaussian Cylinder"
                        gm = multicylinder(3,6,Zoffset=-3);
                        gm = rotate(gm,90,[0 0 0],[0 1 0]);
                        gm = rotate(gm,90);
                        app.DescriptionGaussianSurface.Text = "\bf{Gaussian Surface:}"+newline+" Cylinder along the x-axis with length $L$ and radius $R_g$";
                    case "Gaussian Sphere"
                        gm = multisphere(4);
                        if app.Case == "Solid Sphere" || app.Case == "Spherical Shell"
                            app.DescriptionGaussianSurface.Text = "\bf{Gaussian Surface:}"+newline+" Sphere with radius $R_g > R_s$";
                        else
                            app.DescriptionGaussianSurface.Text = "\bf{Gaussian Surface:}"+newline+" Sphere with radius $R_g$";
                        end
                end

                delete(app.SurfacePlot)
                hold(app.Axes,"on")
                [caz,cel] = view(app.Axes);
                if app.Step == 5
                    app.SurfacePlot = pdegplot(app.Axes,gm,FaceAlpha = 0.5,FaceLabel="on");
                else
                    app.SurfacePlot = pdegplot(app.Axes,gm,FaceAlpha = 0.5);
                end
                delete(findobj(app.Axes,"Type","Quiver"))
                delete(findobj(app.Axes,"Type","Text"))
                hold(app.Axes,"off")
                view(app.Axes,caz,cel);
            end


        end

        function UpdatePanel(app)
            
            if app.Step > 0
                % Display panel
                app.WelcomeLabel.Visible = "off";
                app.QuestPanel.Visible = "on";
                app.CtrlPanel.Visible = "on";
                app.FeedbackPanel.Visible = "on";
                app.CheckAnswerButton.Visible = "on";
                app.NextButton.Visible = "on";
                app.PreviousButton.Visible = "on";
                app.ClearButton.Visible = "on";
                app.ClearButton.Enable = "on";
                if app.Status(app.Step)
                    app.CheckAnswerButton.Enable = "off";
                else
                    app.CheckAnswerButton.Enable = "on";
                end
% [XXX] Should be removed 
                if app.Step < 6
%                if app.Status(app.Step) && app.Step < 6 
% [XXX] Should be uncommented
                    app.NextButton.Enable = "on";
                else
                    app.NextButton.Enable = "off";  
                end
                if app.Step > 1
                    app.PreviousButton.Enable = "on";
                else
                    app.PreviousButton.Enable = "off";
                end

                % Move panel back
                if ~isempty(app.QuestPanel.Children)
                    set(app.QuestPanel.Children,"Parent",app.QuestPanel.UserData);
                    set(app.CtrlPanel.Children,"Parent",app.CtrlPanel.UserData);
                end
                % Bring right panel
                switch app.Step
                    case 1
                        app.Step1QuestLayout.Parent = app.QuestPanel;
                        app.Step1CtrlLayout.Parent = app.CtrlPanel;
                    case 2
                        app.Step2QuestLayout.Parent = app.QuestPanel;
                        app.Step2CtrlLayout.Parent = app.CtrlPanel;
                    case 3
                        app.Step3QuestLayout.Parent = app.QuestPanel;
                        app.Step3CtrlLayout.Parent = app.CtrlPanel;
                    case 4
                        app.Step4QuestLayout.Parent = app.QuestPanel;
                        app.Step4CtrlLayout.Parent = app.CtrlPanel;
                    case 5
                        app.Step5QuestLayout.Parent = app.QuestPanel;
                        app.Step5CtrlLayout.Parent = app.CtrlPanel;
                    case 6
                        app.Step6QuestLayout.Parent = app.QuestPanel;
                        app.Step6CtrlLayout.Parent = app.CtrlPanel;
                end
                app.FeedbackLabel.Text = app.Feedback(app.Step);
            else
                app.WelcomeLabel.Visible = "on";
            end
        end

        function CheckAnswer1(app)
            Answer = [app.CheckBoxNone.Value, app.CheckBoxPlanar.Value, ...
                app.CheckBoxCylindrical.Value, app.CheckBoxSpherical.Value];
            if sum(Answer) > 1
                app.Feedback(1) = "This exercise is expecting a single valid answer.";
                return
            end
            if sum(Answer) == 0
                app.Feedback(1) = "Please select at least one answer above.";
                return;
            end
            if Answer(1)
                app.Feedback(1) = "Incorrect, this distribution displays some symmetry.";
                return;
            end

            switch app.Case
                case "Point Charge"
                    if Answer(2) % Planar
                        app.Feedback(1) = "Not quite. A point charge does not have planar symmetry because the electric field is not confined to a plane.";
                    end
                    if Answer(3) % Cylindrical
                        app.Feedback(1) = "Not quite. A point charge does not have cylindrical symmetry because the electric field is not confined to a cylinder.";
                    end
                    if Answer(4) % Spherical
                        app.Feedback(1) = "Great job! A point charge has spherical symmetry because the electric field is the same in all directions from the charge.";
                        app.Status(1) = 1;
                    end
                case "Infinite Line"
                    if Answer(2)
                        app.Feedback(1) = "Not quite. An infinite line of charge does not have planar symmetry because the electric field is not confined to a plane.";
                    end
                    if Answer(3)
                        app.Feedback(1) = "Excellent! An infinite line of charge has cylindrical symmetry because the electric field is the same in all directions perpendicular to the line.";
                        app.Status(1) = 1;
                    end
                    if Answer(4)
                        app.Feedback(1) = "Not quite. An infinite line of charge does not have spherical symmetry because the electric field is not the same in all directions.";
                    end
                case "Infinite Plane"
                    if Answer(2)
                        app.Feedback(1) = "Perfect! An infinite plane of charge has planar symmetry because the electric field is the same on either side of the plane and perpendicular to it.";
                        app.Status(1) = 1;
                    end
                    if Answer(3)
                        app.Feedback(1) = "Not quite. An infinite plane of charge does not have cylindrical symmetry because the electric field is not confined to a cylinder.";
                    end
                    if Answer(4)
                        app.Feedback(1) = "Not quite. An infinite plane of charge does not have spherical symmetry because the electric field is not the same in all directions.";
                    end
                case "Solid Sphere"
                    if Answer(2)
                        app.Feedback(1) = "Not quite. A sphere of charge does not have planar symmetry because the electric field is not confined to a plane.";
                    end
                    if Answer(3)
                        app.Feedback(1) = "Not quite. A sphere of charge does not have cylindrical symmetry because the electric field is not confined to a cylinder.";
                    end
                    if Answer(4)
                        app.Feedback(1) = "Correct! A sphere of charge has spherical symmetry because the electric field is the same in all directions from the center of the sphere.";
                        app.Status(1) = 1;
                    end
                case "Spherical Shell"
                    if Answer(2)
                        app.Feedback(1) = "Not quite. A spherical shell of charge does not have planar symmetry because the electric field is not confined to a plane.";
                    end
                    if Answer(3)
                        app.Feedback(1) = "Not quite. A spherical shell of charge does not have cylindrical symmetry because the electric field is not confined to a cylinder.";
                    end
                    if Answer(4)
                        app.Feedback(1) = "Correct! A spherical shell of charge has spherical symmetry because the electric field is the same in all directions from the center of the shell.";
                        app.Status(1) = 1;
                    end
            end
        end

        function CheckAnswer2(app)

            if ~app.Step2Check1.Value && ~app.Step2Check2.Value && ~app.Step2Check3.Value && ~app.Step2Check4.Value
                app.Feedback(2) = "Select all that apply.";
                return
            end

            switch app.Case
                case "Point Charge"
                    % Check if right answer
                    if ~app.Step2Check1.Value && ~app.Step2Check2.Value && app.Step2Check3.Value && app.Step2Check4.Value
                        app.Feedback(2) = "Correct! The electric field of a point charge points radially outward (for a positive charge) " + ...
                            "or inward (for a negative charge) from the charge and depends exclusively on $r$ This means the field lines are " + ...
                            "directed straight away from or towards the charge.";
                        app.Status(2) = 1;
                    end

                    % Check for error
                    if app.Step2Check1.Value
                        app.Feedback(2) = "Incorrect. The electric field of a point charge is not constant; it decreases with the square " + ...
                            "of the distance from the charge. ";
                        return
                    end
                    if app.Step2Check2.Value
                        app.Feedback(2) = "Incorrect. The magnitude of the electric field of a point charge does not depend exclusively on " + ...
                            "the $z$-coordinate. Instead, it depends on the radial distance $r$ from the charge. The electric field is " + ...
                            "spherically symmetric, meaning it depends on the distance from the charge in all directions.";
                        return
                    end

                case "Infinite Line"
                    % Check if right answer
                    if ~app.Step2Check1.Value && app.Step2Check2.Value && app.Step2Check3.Value && ~app.Step2Check4.Value
                        app.Feedback(2) = "Correct! The magnitude of the electric field of an infinite line of charge depends exclusively " + ...
                            "on the perpendicular distance $r$ from the line and is radially inward/outward.";
                        app.Status(2) = 1;
                    end

                    % Check for error
                    if app.Step2Check1.Value
                        app.Feedback(2) = "Incorrect. The electric field of an infinite line of charge is not uniform; it decreases with " + ...
                            "the distance from the line.";
                        return
                    end
                    if app.Step2Check4.Value
                        app.Feedback(2) = "Incorrect. The magnitude of the electric field of an infinite line of charge does not depend " + ...
                            "exclusively on the $x$-coordinate. Instead, it depends on the perpendicular distance $r$ from the line. " + ...
                            "The electric field is cylindrically symmetric, meaning it depends on the distance from the line in all " + ...
                            "directions perpendicular to the line.";
                        return
                    end

                case "Infinite Plane"
                    % Check if right answer
                    if app.Step2Check1.Value && ~app.Step2Check2.Value && ~app.Step2Check3.Value && app.Step2Check4.Value
                        app.Feedback(2) = "Correct! The magnitude of the field is constant and it is perpendicular to the plane (inward/outward).";
                        app.Status(2) = 1;
                    end

                    % Check for error
                    if app.Step2Check2.Value
                        app.Feedback(2) = "Incorrect. The electric field of an infinite plane of charge is constant in magnitude but not in direction.";
                        return
                    end
                    if app.Step2Check3.Value
                        app.Feedback(2) = "Incorrect. The magnitude of the electric field of an infinite plane of charge does not depend " + ...
                            "on the distance to the plane. Instead, the electric field is constant in magnitude at any distance from the plane.";
                        return
                    end

                case "Solid Sphere"

                    % Check if right answer
                    if app.Step2Check1.Value && app.Step2Check2.Value && ~app.Step2Check3.Value && ~app.Step2Check4.Value
                        app.Feedback(2) = "Correct! The electric field of a charged sphere radially outward (for a positive charge) or " + ...
                            "inward (for a negative charge) from the charge and depends exclusively on $r$ This means the field lines " + ...
                            "are directed straight away from or towards the charge.";
                        app.Status(2) = 1;
                    end

                    % Check for error
                    if app.Step2Check3.Value
                        app.Feedback(2) = "Incorrect. The electric field of a filled sphere of charge is not uniform; it varies with " + ...
                            "the distance from the center of the sphere. Inside the sphere, the electric field increases linearly with $r$; " + ...
                            "outside the sphere, it decreases with the square of the distance from the center.";
                        return
                    end
                    if app.Step2Check4.Value
                        app.Feedback(2) = "Incorrect. The electric field of a filled sphere of charge is not constant; it varies with " + ...
                            "the distance from the center of the sphere. Inside the sphere, the electric field increases linearly with $r$; " + ...
                            "outside the sphere, it decreases with the square of the distance from the center.";
                        return
                    end

                case "Spherical Shell"

                    % Check if right answer
                    if ~app.Step2Check1.Value && app.Step2Check2.Value && app.Step2Check3.Value && ~app.Step2Check4.Value
                        app.Feedback(2) = "Correct! The electric field of a charged spherical shell radially outward (for a positive charge) " + ...
                            "or inward (for a negative charge) from the charge and depends exclusively on $r$ This means the field lines " + ...
                            "are directed straight away from or towards the charge.";
                        app.Status(2) = 1;
                    end

                    % Check for error
                    if app.Step2Check1.Value
                        app.Feedback(2) = "Incorrect. The electric field of a filled sphere of charge is not constant; it varies with the " + ...
                            "distance from the center of the sphere. Inside the sphere, the electric field increases linearly with $r$; " + ...
                            "outside the sphere, it decreases with the square of the distance from the center.";
                        return
                    end
                    if app.Step2Check4.Value
                        app.Feedback(2) = "Incorrect. The electric field of a filled sphere of charge is not constant; it varies with " + ...
                            "the distance from the center of the sphere. Inside the sphere, the electric field increases linearly with $r$; " + ...
                            "outside the sphere, it decreases with the square of the distance from the center.";
                        return
                    end

            end
        end

        function CheckAnswer3(app)
            switch app.Case
                case "Point Charge"
                    switch (app.SurfaceDropDown.Value)
                        case "??"
                            app.Feedback(3) = "Please, select a valid answer.";
                        case "Gaussian Sphere"
                            app.Feedback(3) = "Correct! For a point charge, a Gaussian sphere is the most appropriate choice " + ...
                                "because it simplifies the calculation of the electric flux due to the spherical symmetry of " + ...
                                "the charge distribution.";
                            app.Status(3) = 1;
                        case "Gaussian Cylinder"
                            app.Feedback(3) = " Incorrect. A Gaussian cylinder is not suitable for a point charge because it " + ...
                                "does not take advantage of the spherical symmetry.";
                        case "Gaussian Cube"
                            app.Feedback(3) = "Incorrect. A Gaussian cube is not suitable for a point charge because it does " + ...
                                "not take advantage of the spherical symmetry.";
                        otherwise
                            error("CheckAnswer3:CaseNotImplemented")
                    end
                case "Infinite Line"
                    switch (app.SurfaceDropDown.Value)
                        case "??"
                            app.Feedback(3) = "Please, select a valid answer.";
                        case "Gaussian Sphere"
                            app.Feedback(3) = "Incorrect. A Gaussian sphere is not suitable for an infinite line charge " + ...
                                "because it does not take advantage of the cylindrical symmetry.";
                        case "Gaussian Cylinder"
                            app.Feedback(3) = "Correct! For an infinite line charge, a Gaussian cylinder is the most " + ...
                                "appropriate choice because it simplifies the calculation of the electric flux due to the " + ...
                                "cylindrical symmetry of the charge distribution.";
                            app.Status(3) = 1;
                        case "Gaussian Cube"
                            app.Feedback(3) = "Incorrect. A Gaussian cube is not suitable for an infinite line charge " + ...
                                "because it does not take advantage of the cylindrical symmetry.";
                        otherwise
                            error("CheckAnswer3:CaseNotImplemented")
                    end

                case "Infinite Plane"
                    switch (app.SurfaceDropDown.Value)
                        case "??"
                            app.Feedback(3) = "Please, select a valid answer.";
                        case "Gaussian Sphere"
                            app.Feedback(3) = "Incorrect. A Gaussian sphere is not suitable for an infinite plane charge " + ...
                                "because it does not take advantage of the planar symmetry.";
                        case "Gaussian Cylinder"
                            app.Feedback(3) = "Incorrect. A Gaussian cylinder is not suitable for an infinite plane charge " + ...
                                "because it does not take advantage of the planar symmetry.";
                        case "Gaussian Cube"
                            app.Feedback(3) = "Correct! For an infinite plane of charge, a Gaussian cube—or, more generally,"+...
                                " any prism (such as a rectangular box) or even a cylinder can be an appropriate choice, as"+...
                                " long as it is oriented so that its flat faces (or the ends of the cylinder) are parallel to"+...
                                " the plane. This orientation takes advantage of the planar symmetry of the charge distribution"+...
                                " and simplifies the calculation of the electric flux.";
                            app.Status(3) = 1;
                        otherwise
                            error("CheckAnswer3:CaseNotImplemented")
                    end

                case "Solid Sphere"
                    switch (app.SurfaceDropDown.Value)
                        case "??"
                            app.Feedback(3) = "Please, select a valid answer.";
                        case "Gaussian Sphere"
                            app.Feedback(3) = "Correct! For a uniformly charged sphere, a Gaussian sphere is the most appropriate " + ...
                                "choice because it simplifies the calculation of the electric flux due to the spherical symmetry " + ...
                                "of the charge distribution.";
                            app.Status(3) = 1;
                        case "Gaussian Cylinder"
                            app.Feedback(3) = "Incorrect. A Gaussian cylinder is not suitable for a uniformly charged sphere " + ...
                            "because it does not take advantage of the spherical symmetry.";
                        case "Gaussian Cube"
                            app.Feedback(3) = "Incorrect. A Gaussian cube is not suitable for a uniformly charged sphere because " + ...
                                "it does not take advantage of the spherical symmetry.";
                        otherwise
                            error("CheckAnswer3:CaseNotImplemented")
                    end

                case "Spherical Shell"
                    switch (app.SurfaceDropDown.Value)
                        case "??"
                            app.Feedback(3) = "Please, select a valid answer.";
                        case "Gaussian Sphere"
                            app.Feedback(3) = "Correct! For a uniformly charged spherical shell, a Gaussian sphere is the most " + ...
                                "appropriate choice because it simplifies the calculation of the electric flux due to the spherical " + ...
                                "symmetry of the charge distribution.";
                            app.Status(3) = 1;
                        case "Gaussian Cylinder"
                            app.Feedback(3) = "Incorrect. A Gaussian cylinder is not suitable for a uniformly charged spherical " + ...
                                "shell because it does not take advantage of the spherical symmetry.";
                        case "Gaussian Cube"
                            app.Feedback(3) = "Incorrect. A Gaussian cube is not suitable for a uniformly charged spherical shell " + ...
                                "because it does not take advantage of the spherical symmetry.";
                        otherwise
                            error("CheckAnswer3:CaseNotImplemented")
                    end

            end
        end

        function CheckAnswer4(app)
            switch app.Case
                case "Point Charge"
                    syms q R_g %#ok<NASGU>
                    Answer = str2sym(app.Step4Answer.Value);
                    if isequal(Answer,q)
                        app.Feedback(4) = "Correct!";
                        app.Status(4) = 1;
                        return
                    end
                    app.Feedback(4) = "Incorrect! The only charge contained in the Gaussian surface is q. Try again!";
                    return
                case "Infinite Line"
                    syms lambda L_g R_g %#ok<NASGU>
                    Answer = str2sym(app.Step4Answer.Value);
                    if isequal(Answer,lambda*L_g)
                        app.Feedback(4) = "Correct!";
                        app.Status(4) = 1;
                        return
                    end

                case "Infinite Plane"
                    syms L_g
                    sigma = sym("sigma");
                    Answer = str2sym(app.Step4Answer.Value);
                    if isequal(Answer,sigma*L_g^2)
                        app.Feedback(4) = "Correct!";
                        app.Status(4) = 1;
                        return
                    else
                        app.Feedback(4) = "The total charge inside the Gaussian surface should be the surface of the blue plane including the cube multiplied by the charge density.";
                        return
                    end

                case "Solid Sphere"
                    syms rho R_g R_s
                    Answer = str2sym(app.Step4Answer.Value);
                    if isequal(Answer,rho*4/3*pi*R_s^3)
                        app.Feedback(4) = "Correct!";
                        app.Status(4) = 1;
                        return
                    end
                    if isequal(Answer,rho*4/3*pi*R_g^3)
                        app.Feedback(4) = "Incorrect! You are on the right track, but you are using the wrong radius, " + ...
                            "the total charge only depends on the geometrical feature of the distribution itself.";
                        return
                    end
                case "Spherical Shell"
                    syms R_g R_s
                    sigma = sym("sigma");
                    Answer = str2sym(app.Step4Answer.Value);
                    if isequal(Answer,4*sigma*pi*R_s^2)
                        app.Feedback(4) = "Correct!";
                        app.Status(4) = 1;
                        return
                    end
                    if isequal(Answer,4*sigma*pi*R_g^2)
                        app.Feedback(4) = "Incorrect! You are on the right track, but you are using the wrong radius, " + ...
                            "the total charge only depends on the geometrical feature of the distribution itself.";
                        return
                    end
                    if isequal(Answer,4/3*sigma*pi*R_s^3)
                        app.Feedback(4) = "Incorrect! You are multiplying the charge density by the sphere volume, you should be multiplying by the sphere surface.";
                        return
                    end
            end
            app.Feedback(4) = "Incorrect, try again!";
        end

        function CheckAnswer5(app)
            switch app.Case
                case "Point Charge"
                    % app.Step5Label1.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
                    % app.Step5Label2.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
                    % app.Step5Label3.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
                    % app.Step5Label4.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false
                    if app.Step5Check1.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$.";
                        return
                    end
                    if ~app.Step5Check2.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check3.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check4.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    app.Feedback(5) = "Good job! You found the integral for the point charge.";
                    app.Status(5) = 1;
                case "Infinite Line"
                    % app.Step5Label1.Text = "$\int_{F_3} \vec{E} \cdot d\vec{S} = 2\pi R_g L_g E(R_g)$"; % true
                    % app.Step5Label2.Text = "$\int_{F_2} \vec{E} \cdot d\vec{S} \neq 0$"; % false
                    % app.Step5Label3.Text = "$\int_{F_2} \vec{E} \cdot d\vec{S} = 0$"; % true
                    % app.Step5Label4.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = \int_{F_2} E \cdot d\vec{S}$"; % true
                    if ~app.Step5Check1.Value
                        app.Feedback(5) = "Incorrect! Your expression for the integral over $F_3$ is wrong.";
                        return
                    end
                    if app.Step5Check2.Value
                        app.Feedback(5) = "Incorrect! The integral over $F_2$ must be null.";
                        return
                    end
                    if ~app.Step5Check3.Value
                        app.Feedback(5) = "Incorrect! The integral over $F_2$ must be null.";
                        return
                    end
                    if ~app.Step5Check4.Value
                        app.Feedback(5) = "Incorrect! The integral over $F_1$ and $F_2$ must be the same because " + ...
                            "of the symmetrical nature of the distribution.";
                        return
                    end
                    app.Feedback(5) = "Good job! You found all the components for the infinite charged line.";
                    app.Status(5) = 1;
                case "Infinite Plane"
                    % app.Step5Label1.Text = "$\int_{F_3} \vec{E} \cdot d\vec{S} = L_g^2 E(L_g/2)$"; % false
                    % app.Step5Label2.Text = "$\int_{F_3} \vec{E} \cdot d\vec{S} = \int_{F_4} \vec{E} \cdot d\vec{S}$ = \int_{F_5} \vec{E} \cdot d\vec{S} = \int_{F_6} \vec{E} \cdot d\vec{S} = 0"; % true
                    % app.Step5Label3.Text = "$\int_{F_2} \vec{E} \cdot d\vec{S} = L_g^2 E(L_g/2)$"; % true
                    % app.Step5Label4.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = L_g^2 E(-L_g/2)$"; % true
                    if app.Step5Check1.Value
                        app.Feedback(5) = "Incorrect! You expression for the integral over $F_3$ is wrong.";
                        return
                    end
                    if ~app.Step5Check2.Value
                        app.Feedback(5) = "Incorrect. The integral over $F_3$, $F_4$, $F_5$, and $F_6$, must be " + ...
                            "the same because of the symmetrical nature of the distribution.";
                        return
                    end
                    if ~app.Step5Check3.Value
                        app.Feedback(5) = "Incorrect! You expression for the integral over $F_2$ is wrong.";
                        return
                    end
                    if ~app.Step5Check4.Value
                        app.Feedback(5) = "Incorrect! You expression for the integral over $F_1$ is wrong.";
                        return
                    end
                    app.Feedback(5) = "Good job! You found all the components for the infinite charged plane.";
                    app.Status(5) = 1;
                case "Solid Sphere"
                    % app.Step5Label1.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
                    % app.Step5Label2.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
                    % app.Step5Label3.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
                    % app.Step5Label4.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false
                    if app.Step5Check1.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$.";
                        return
                    end
                    if ~app.Step5Check2.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check3.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check4.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    app.Feedback(5) = "Good job! You found all the components for the solid charged sphere.";
                    app.Status(5) = 1;
                case "Spherical Shell"
                    % app.Step5Label1.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
                    % app.Step5Label2.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
                    % app.Step5Label3.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
                    % app.Step5Label4.Text = "$\int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false
                    if app.Step5Check1.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$.";
                        return
                    end
                    if ~app.Step5Check2.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check3.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    if app.Step5Check4.Value
                        app.Feedback(5) = "Incorrect! The electric field should be evaluated at $R_g$ and the area computed at $R_g$ as well.";
                        return
                    end
                    app.Feedback(5) = "Good job! You found all the components for the infinite charged line.";
                    app.Status(5) = 1;
            end
        end

        function CheckAnswer6(app)
            switch app.Case
                case "Point Charge"
                    % app.Step6Label1.Text = "$E(R_g) = \frac{3}{4\pi\varepsilon_0} \frac{q}{R_g^2}$";
                    % app.Step6Label2.Text = "$E(R_g) =\frac{1}{4\pi\varepsilon_0} \frac{q}{R_g^2}$"; TRUE
                    % app.Step6Label3.Text = "$E(R_g) = \frac{qR_g^2}{4\pi\varepsilon_0}$";
                    % app.Step6Label4.Text = "$E(R_g) = \frac{1}{2\pi\varepsilon_0} \frac{q}{R_g^2}$";
                    if app.Step6Check1.Value
                        app.Feedback(6) = "Incorrect. While the form of this expression is similar to the correct formula, the numerator should be 1, not 3. The constant in Coulomb’s law is always $\frac{1}{4\pi\varepsilon_0}$.";
                        return
                    end
                    if app.Step6Check2.Value
                        app.Feedback(6) = "Correct! This is the correct expression for the electric field generated by a point charge $q$ at a distance $R_g$. It follows directly from Coulomb’s law. Well done!";
                        app.Status(6) = 1;
                        return
                    end
                    if app.Step6Check3.Value
                        app.Feedback(6) = "Incorrect. This expression has the distance $R_g$ in the numerator, but it should be in the denominator squared. The electric field from a point charge decreases with the square of the distance, not increases. Remember, it should be proportional to $\frac{1}{R_g^2}$.";
                        return
                    end
                    if app.Step6Check4.Value
                        app.Feedback(6) = "Incorrect. This answer has the correct variables and structure, but the constant is wrong. The correct constant is $\frac{1}{4\pi\varepsilon_0}$, not $\frac{1}{2\pi\varepsilon_0}$.";
                    end
                case "Infinite Line"
                    if app.Step6Check1.Value
                        app.Feedback(6) = "Incorrect. The electric field generated by an infinite line charge is not " + ...
                            "proportional to $\frac{1}{4\pi\varepsilon_0}$. The correct expression should involve $\frac{1}{2\pi\varepsilon_0}$.";
                        return
                    end
                    if app.Step6Check2.Value
                        app.Feedback(6) = "Incorrect. The variable $R_s$ is not defined in the context of an infinite line charge. " + ...
                            "The correct variable should be $R_g$, which represents the distance from the line charge.";
                        return
                    end
                    if app.Step6Check3.Value
                        app.Feedback(6) = "Incorrect. The variable $R_s$ is not defined in the context of an infinite line charge. " + ...
                            "The correct variable should be $R_g$, which represents the distance from the line charge.";
                        return
                    end
                    if app.Step6Check4.Value
                        app.Feedback(6) = "Correct. The electric field generated by an infinite line charge is given by " + ...
                            "$E(R_g)=\frac{\lambda}{2\pi\varepsilon_0 R_g}$.";
                        app.Status(6) = 1;
                        return
                    end
                case "Infinite Plane"
                    if app.Step6Check1.Value
                        app.Feedback(6) = "Incorrect. The electric field generated by an infinite plane charge is not proportional " + ...
                            "to $\frac{1}{4\varepsilon_0}$. The correct expression should involve $\frac{1}{2\varepsilon_0}$.";
                        return
                    end
                    if app.Step6Check2.Value
                        app.Feedback(6) = "Correct. The electric field generated by an infinite plane charge is given by $E=\frac{1}{2\varepsilon_0\sigma}$.";
                        app.Status(6) = 1;
                        return
                    end
                    if app.Step6Check3.Value
                        app.Feedback(6) = "Incorrect. The variable $L_g$ is not defined in the context of an infinite plane charge. ";
                        return
                    end
                    if app.Step6Check4.Value
                        app.Feedback(6) = "Incorrect. The electric field generated by an infinite plane charge does not involve $\pi$.";
                        return
                    end
                case "Solid Sphere"
                    if app.Step6Check1.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a uniformly charged sphere is not given by this expression. " + ...
                            "The correct expression for the electric field outside the sphere should involve $\frac{R_s^3}{R_g^2}$ in the fraction.";
                        return
                    end
                    if app.Step6Check2.Value
                         app.Feedback(6) = "Correct. The electric field outside a uniformly charged sphere is given by " + ...
                            "$E(R_g) = \frac{\rho}{\varepsilon_0}\frac{R_s^3}{R_g^2}$.";
                        app.Status(6) = 1;
                        return
                    end
                    if app.Step6Check3.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a uniformly charged sphere is not given by this expression. " + ...
                            "The correct expression for the electric field outside the sphere should involve $\frac{R_s^3}{R_g^2}$ in the fraction.";
                        return
                    end
                    if app.Step6Check4.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a uniformly charged sphere does not involve $R_g^3$ in the numerator.";
                        return
                    end
                case "Spherical Shell"
                    if app.Step6Check1.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a charged sphere does not depend on $R_g^2$ in the numerator.";
                        return
                    end
                    if app.Step6Check2.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a charged sphere does not involve $2\pi$ or $R_g^2$ in the numerator.";
                        return
                    end
                    if app.Step6Check3.Value
                        app.Feedback(6) = "Incorrect. The electric field outside a charged sphere does not involve $2\pi$. " + ...
                            "The correct expression should involve $\frac{R_s^2}{R_g^2}$ in the fraction.";
                        return
                    end
                    if app.Step6Check4.Value
                        app.Feedback(6) = "Correct. The electric field outside a charged sphere is given by "+newline + ...
                            "$E(R_g) = \frac{\sigma}{\varepsilon_0} \frac{R_s^2}{R_g^2}$.";
                        app.Status(6) = 1;
                        return
                    end
            end
        end
        
        function SetFontSize(app,size)
            Components = fieldnames(app);
            for i = 1:numel(Components)
                if isprop(app.(Components{i}),"FontSize")
                    set(app.(Components{i}),"FontSize",size);
                end
            end    
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, Case)
            arguments
                app (1,1) GaussLaw
                Case (1,1) string = "";
            end
            % Set Font Size to 12
            SetFontSize(app,12);

            % Save original parents
            app.Step1QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step2QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step3QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step4QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step5QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step6QuestLayout.UserData = app.Step1QuestLayout.Parent;
            app.Step1CtrlLayout.UserData = app.Step1CtrlLayout.Parent;
            app.Step2CtrlLayout.UserData = app.Step1CtrlLayout.Parent;
            app.Step3CtrlLayout.UserData = app.Step1CtrlLayout.Parent;
            app.Step4CtrlLayout.UserData = app.Step1CtrlLayout.Parent;
            app.Step5CtrlLayout.UserData = app.Step1CtrlLayout.Parent;
            app.Step6CtrlLayout.UserData = app.Step1CtrlLayout.Parent;

            switch Case
                case "Random"
                    RandomMenuSelected(app);
                case "Point Charge"
                    PointChargeMenuSelected(app);
                case "Infinite Line"
                    InfiniteLineMenuSelected(app);
                case "Infinite Plane"
                    InfinitePlaneMenuSelected(app);
                case "Solid Sphere"
                    SolidSphereMenuSelected(app);
                case "Spherical Shell"
                    SphericalShellMenuSelected(app);
            end
        end

        % Menu selected function: RandomMenu
        function RandomMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Random");
                        delete(app)
                        return
                    case "No"
                end
            end
            
            % Select a case and display it:
            PossibleCases = ["Point Charge", "Infinite Line", "Infinite Plane", ...
                "Solid Sphere", "Spherical Shell"];
            CaseId = randi(numel(PossibleCases));
            switch PossibleCases(CaseId)
                case "Point Charge"
                    PointChargeMenuSelected(app);
                case "Infinite Line"
                    InfiniteLineMenuSelected(app);
                case "Infinite Plane"
                    InfinitePlaneMenuSelected(app);
                case "Solid Sphere"
                    SolidSphereMenuSelected(app);
                case "Spherical Shell"
                    SphericalShellMenuSelected(app);
            end
        end

        % Menu selected function: InfiniteLineMenu
        function InfiniteLineMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Infinite Line");
                        delete(app)
                        return
                    case "No"
                end
            end
            
            app.Step = 1;
            app.Case = "Infinite Line";

            % Set Q2
            app.Step2Label1.Text = "uniform.";
            app.Step2Label2.Text = "magnitude exclusively depends on $r$.";
            app.Step2Label3.Text = "points radially outward(+)/inward(-).";
            app.Step2Label4.Text = "magnitude exclusively depends on $x$.";

            % Set Q4
            app.Step4Sym.Text = "Enter your answer above using mathematical expression and the predefined symbol:" + newline +...
                "  - lambda for the charge density $\lambda"+newline+...
                "  - L_g for the cylinder length $L_g$ "+newline+...
                "  - R_g for the cylinder radius $R_g$ "+newline+...
                "  - pi for $\pi$";


            % Set Q5
            app.Step5Label.Text = "We'll now try to simplify the integral:"+newline+"$\int \vec{E} \cdot d\vec{S} = \sum_{i = 1}^3 \int_{F_i} {\vec{E}\cdot d\vec{S}}$"+newline+newline+"\bf Which of the following options apply?";
            app.Step5Label1.Text = "$\displaystyle \int_{F_3} \vec{E} \cdot d\vec{S} = 2\pi R_g L_g E(R_g)$"; % true
            app.Step5Label2.Text = "$\displaystyle \int_{F_2} \vec{E} \cdot d\vec{S} \neq 0$"; % false
            app.Step5Label3.Text = "$\displaystyle \int_{F_2} \vec{E} \cdot d\vec{S} = 0$"; % true
            app.Step5Label4.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = \int_{F_2} E \cdot d\vec{S}$"; % true

            % Set Q6
            app.Step6Label1.Text = "$E(R_g) = \frac{\lambda}{4\pi\varepsilon_0 R_g}$";
            app.Step6Label2.Text = "$E(R_g) = \frac{\lambda}{2\pi\varepsilon_0 R_s}$";
            app.Step6Label3.Text = "$E(R_g) = \frac{\lambda}{2\pi\varepsilon_0 R_s}$";
            app.Step6Label4.Text = "$E(R_g) = \frac{\lambda}{2\pi\varepsilon_0 R_g}$"; % Good answer

            % Apply change
            Update(app);
        end

        % Menu selected function: InfinitePlaneMenu
        function InfinitePlaneMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Infinite Plane");
                        delete(app)
                        return
                    case "No"
                end
            end

            app.Step = 1;
            app.Case = "Infinite Plane";

            % Set Q2
            app.Step2Label1.Text = "perpendicular to the plane.";
            app.Step2Label2.Text = "uniform.";
            app.Step2Label3.Text = "magnitude depends on distance to plane.";
            app.Step2Label4.Text = "magnitude is constant.";

            % Set Q4
            app.Step4Sym.Text = "Enter your answer above using mathematical expression and the predefined symbol:" + newline +...
                "  - sigma for the charge density $\sigma"+newline+...
                "  - L_g for the cube length $L_g$ ";

            % Set Q5
            app.Step5Label.Text = "We'll now try to simplify the integral:"+newline+"$\int \vec{E}\cdot d\vec{S} = \sum_{i = 1}^6 \int_{F_i} {\vec{E}\cdot d\vec{S}}$"+newline+newline+"\bf Which of the following options apply?";
            app.Step5Label1.Text = "$\displaystyle \int_{F_3} \vec{E} \cdot d\vec{S} = L_g^2 E(L_g/2)$"; % false
            app.Step5Label2.Text = "$\displaystyle \int_{F_3} \vec{E} \cdot d\vec{S} = \int_{F_4} \vec{E} \cdot d\vec{S} = \int_{F_5} \vec{E} \cdot d\vec{S} = \int_{F_6} \vec{E} \cdot d\vec{S} = 0$"; % true
            app.Step5Label3.Text = "$\displaystyle \int_{F_2} \vec{E} \cdot d\vec{S} = L_g^2 E(L_g/2)$"; % true
            app.Step5Label4.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = L_g^2 E(-L_g/2)$"; % true

            % Set Q6
            app.Step6Label1.Text = "$E(R_g) = \frac{\sigma}{4\varepsilon_0}$";
            app.Step6Label2.Text = "$E(R_g) = \frac{\sigma}{2\varepsilon_0}$"; % Good Answer
            app.Step6Label3.Text = "$E(R_g) = \frac{\sigma L_g}{2\varepsilon_0}$";
            app.Step6Label4.Text = "$E(R_g) = \frac{\sigma}{2\pi \varepsilon_0}$";

            % Apply change
            Update(app);
        end

        % Menu selected function: SolidSphereMenu
        function SolidSphereMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Solid Sphere");
                        delete(app)
                        return
                    case "No"
                end
            end

            app.Step = 1;
            app.Case = "Solid Sphere";

            % Set Q2
            app.Step2Label1.Text = "magnitude exclusively depends on (r).";
            app.Step2Label2.Text = "points radially outward(+)/inward(-).";
            app.Step2Label3.Text = "uniform.";
            app.Step2Label4.Text = "constant.";

            % Set Q4
            app.Step4Sym.Text = "Enter your answer above using mathematical expression and the predefined symbol:" + newline +...
                "  - rho for the charge density $\rho"+newline+...
                "  - R_g for the Gauss sphere radius $R_g$ "+newline+...
                "  - R_s for the sphere radius $R_s$ "+newline+...
                "  - pi for $\pi$";

            % Set Q5
            app.Step5Label.Text =  "We'll now try to simplify the integral:"+newline+"$\int_{F_1} E \cdot dS$"+newline+newline+"\bf Which of the following options apply?";
            app.Step5Label1.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
            app.Step5Label2.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
            app.Step5Label3.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
            app.Step5Label4.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false

            % Set Q6
            app.Step6Label1.Text = "$E(R_g) = \frac{\rho}{\varepsilon_0}\frac{R_s^2}{R_g^2}$"; 
            app.Step6Label2.Text = "$E(R_g) = \frac{\rho}{\varepsilon_0}\frac{R_s^3}{R_g^2}$"; 
            app.Step6Label3.Text = "$E(R_g) = \frac{\rho}{\varepsilon_0}\frac{R_g^2}{R_s^2}$"; % Good answer
            app.Step6Label4.Text = "$E(R_g) = \frac{\rho}{\varepsilon_0}\frac{R_g^3}{R_s^2}$"; 


            % Apply change
            Update(app);
        end

        % Menu selected function: SphericalShellMenu
        function SphericalShellMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Spherical Shell");
                        delete(app)
                        return
                    case "No"
                end
            end
            
            app.Step = 1;
            app.Case = "Spherical Shell";

            % Set Q2
            app.Step2Label1.Text = "uniform.";
            app.Step2Label2.Text = "points radially outward(+/inward(-).";
            app.Step2Label3.Text = "magnitude exclusively depends on (r).";
            app.Step2Label4.Text = "constant.";

            % Set Q4
            app.Step4Sym.Text = "Enter your answer above using mathematical expression and the predefined symbol:" + newline +...
                "  - sigma for the charge density $\sigma"+newline+...
                "  - R_g for the Gauss sphere radius $R_g$ "+newline+...
                "  - R_s for the spherical shell radius $R_s$ "+newline+...
                "  - pi for $\pi$";

            % Set Q5
            app.Step5Label.Text =  "We'll now try to simplify the integral:"+newline+"$\int_{F_1} \vec{E} \cdot d\vec{S}$"+newline+newline+"\bf Which of the following options apply?";
            app.Step5Label1.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
            app.Step5Label2.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
            app.Step5Label3.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
            app.Step5Label4.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false

            % Set Q6
            app.Step6Label1.Text = "$E(R_g) = \frac{\sigma}{\varepsilon_0}\frac{R_g^2}{R_s^2}$";
            app.Step6Label2.Text = "$E(R_g) = \frac{\sigma}{\varepsilon_0}\frac{2\pi R_g^2}{R_s^2}$";
            app.Step6Label3.Text = "$E(R_g) = \frac{\sigma}{\varepsilon_0}\frac{2\pi R_s^2}{R_g^2}$";
            app.Step6Label4.Text = "$E(R_g) = \frac{\sigma}{\varepsilon_0}\frac{R_s^2}{R_g^2}$"; % Good Answer

            % Apply change
            Update(app);
        end

        % Menu selected function: PointChargeMenu
        function PointChargeMenuSelected(app, event)
            if app.Step > 0
                Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
                switch Answer
                    case "Yes"
                        GaussLaw("Point Charge");
                        delete(app)
                        return
                    case "No"
                end
            end
            
            app.Step = 1;
            app.Case = "Point Charge";

            % Set Q2
            app.Step2Label1.Text = "is constant.";
            app.Step2Label2.Text = "magnitude exclusively depends on $z$.";
            app.Step2Label3.Text = "magnitude exclusively depends on $r$.";
            app.Step2Label4.Text = "points radially outward/inward.";

            % Set Q4
            app.Step4Sym.Text = "Enter your answer above using mathematical expression and the predefined symbol:" + newline +...
                "  - q for the charge $q$"+newline+...
                "  - R_g for the Gauss sphere radius $R_g$ "+newline+...
                "  - pi for $\pi$";

            % Set Q5
            app.Step5Label.Text =  "We'll now try to simplify the integral:"+newline+"$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S}$" + newline + ...
                + newline + "\bf Which of the following options apply?";
            app.Step5Label1.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_s)$"; % false
            app.Step5Label2.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_g^2 E(R_g)"; % true
            app.Step5Label3.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_s)$"; % false
            app.Step5Label4.Text = "$\displaystyle \int_{F_1} \vec{E} \cdot d\vec{S} = 4 \pi R_s^2 E(R_g)$"; % false

            % Set Q6
            app.Step6Label1.Text = "$E(R_g) = \frac{3}{4\pi\varepsilon_0} \frac{q}{R_g^2}$";
            app.Step6Label2.Text = "$E(R_g) = \frac{1}{4\pi\varepsilon_0} \frac{q}{R_g^2}$";
            app.Step6Label3.Text = "$E(R_g) = \frac{qR_g^2}{4\pi\varepsilon_0}$";
            app.Step6Label4.Text = "$E(R_g) = \frac{1}{2\pi\varepsilon_0} \frac{q}{R_g^2}$";

            % Apply change
            Update(app);
        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
            value = app.SurfaceDropDown.Value;
            switch value
                case "??"
                    app.Feedback(app.Step) = "Please select a valid Gaussian Surface";
                    app.GaussChoice = string([]);
                case "Gaussian Cube"
                    app.Feedback(app.Step) = "";
                    app.GaussChoice = value;
                case "Gaussian Cylinder"
                    app.Feedback(app.Step) = "";
                    app.GaussChoice = value;
                case "Gaussian Sphere"
                    app.Feedback(app.Step) = "";
                    app.GaussChoice = value;
            end
            Update(app);
        end

        % Button pushed function: CheckAnswerButton
        function CheckAnswerButtonPushed(app, event)
            switch app.Step
                case 1
                    CheckAnswer1(app);
                case 2
                    CheckAnswer2(app);
                case 3
                    CheckAnswer3(app);
                case 4
                    CheckAnswer4(app);
                case 5
                    CheckAnswer5(app);
                case 6
                    CheckAnswer6(app);
                otherwise
                    error("Step not implemented.")
            end
            Update(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.Step < 6
                app.Step = app.Step + 1;
                Update(app);
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.Step > 1
                app.Step = app.Step - 1;
                Update(app);
            end
        end

        % Value changed function: Step6Check1
        function Step6Check1ValueChanged(app, event)
            value = app.Step6Check1.Value;
            if value
                app.Step6Check2.Value = false;
                app.Step6Check3.Value = false;
                app.Step6Check4.Value = false;
            end
        end

        % Value changed function: Step6Check2
        function Step6Check2ValueChanged(app, event)
            value = app.Step6Check2.Value;
            if value
                app.Step6Check1.Value = false;
                app.Step6Check3.Value = false;
                app.Step6Check4.Value = false;
            end
        end

        % Value changed function: Step6Check3
        function Step6Check3ValueChanged(app, event)
            value = app.Step6Check3.Value;
            if value
                app.Step6Check1.Value = false;
                app.Step6Check2.Value = false;
                app.Step6Check4.Value = false;
            end
        end

        % Value changed function: Step6Check4
        function Step6Check4ValueChanged(app, event)
            value = app.Step6Check4.Value;
            if value
                app.Step6Check1.Value = false;
                app.Step6Check2.Value = false;
                app.Step6Check3.Value = false;
            end
        end

        % Size changed function: GaussLabUIFigure
        function GaussLabUIFigureSizeChanged(app, event)
            % Initial position = [100,100,789,553]; % [left, bottom, width, height]
            position = app.GaussLabUIFigure.Position;
            xScale = round(position(3)/789,1);
            yScale = round(position(4)/553,1);
            FontSize = round(12 * min(xScale,yScale));
            SetFontSize(app,max(FontSize,8));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            Answer = questdlg("This action will erase all progress. Are you sure you want to proceed?","Confirm Action","Yes","No","No");
            switch Answer
                case "Yes"
                    GaussLaw;
                    delete(app)
                    return
                case "No"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GaussLabUIFigure = uifigure('Visible', 'off');
            app.GaussLabUIFigure.Name = 'Gauss Lab';
            app.GaussLabUIFigure.SizeChangedFcn = createCallbackFcn(app, @GaussLabUIFigureSizeChanged, true);
            app.SelectCaseMenu = uimenu(app.GaussLabUIFigure);
            app.SelectCaseMenu.Text = 'Select Case';
            app.RandomMenu = uimenu(app.SelectCaseMenu);
            app.RandomMenu.MenuSelectedFcn = createCallbackFcn(app, @RandomMenuSelected, true);
            app.RandomMenu.Text = 'Random';
            app.PointChargeMenu = uimenu(app.SelectCaseMenu);
            app.PointChargeMenu.MenuSelectedFcn = createCallbackFcn(app, @PointChargeMenuSelected, true);
            app.PointChargeMenu.Text = 'Point Charge';
            app.InfiniteLineMenu = uimenu(app.SelectCaseMenu);
            app.InfiniteLineMenu.MenuSelectedFcn = createCallbackFcn(app, @InfiniteLineMenuSelected, true);
            app.InfiniteLineMenu.Text = 'Infinite Line';
            app.InfinitePlaneMenu = uimenu(app.SelectCaseMenu);
            app.InfinitePlaneMenu.MenuSelectedFcn = createCallbackFcn(app, @InfinitePlaneMenuSelected, true);
            app.InfinitePlaneMenu.Text = 'Infinite Plane';
            app.SolidSphereMenu = uimenu(app.SelectCaseMenu);
            app.SolidSphereMenu.MenuSelectedFcn = createCallbackFcn(app, @SolidSphereMenuSelected, true);
            app.SolidSphereMenu.Text = 'Solid Sphere';
            app.SphericalShellMenu = uimenu(app.SelectCaseMenu);
            app.SphericalShellMenu.MenuSelectedFcn = createCallbackFcn(app, @SphericalShellMenuSelected, true);
            app.SphericalShellMenu.Text = 'Spherical Shell';
            app.GeneralArea = uigridlayout(app.GaussLabUIFigure);
            app.Axes = uiaxes(app.GeneralArea);
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Axes.Visible = 'off';
            app.CheckAnswerButton = uibutton(app.GeneralArea, 'push');
            app.CheckAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswerButtonPushed, true);
            app.CheckAnswerButton.Enable = 'off';
            app.CheckAnswerButton.Visible = 'off';
            app.CheckAnswerButton.Interpreter = 'latex';
            app.CheckAnswerButton.Text = 'Check Answer';
            app.FeedbackPanel = uipanel(app.GeneralArea);
            app.FeedbackPanel.Visible = 'off';
            app.FeedbackLayout = uigridlayout(app.FeedbackPanel);
            app.FeedbackLabel = uilabel(app.FeedbackLayout);
            app.FeedbackLabel.Interpreter = 'latex';
            app.FeedbackLabel.Text = '';
            app.CtrlPanel = uipanel(app.GeneralArea);
            app.CtrlPanel.Visible = 'off';
            app.QuestPanel = uipanel(app.GeneralArea);
            app.QuestPanel.Visible = 'off';
            app.DescriptionGaussianSurface = uilabel(app.GeneralArea);
            app.DescriptionGaussianSurface.Interpreter = 'latex';
            app.DescriptionGaussianSurface.Text = '';
            app.DescriptionDistribution = uilabel(app.GeneralArea);
            app.DescriptionDistribution.Interpreter = 'latex';
            app.DescriptionDistribution.Text = '';
            app.PreviousButton = uibutton(app.GeneralArea, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Icon = 'arrowNavigationWest.svg';
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Tooltip = {'Previous Question'};
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.GeneralArea, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'arrowNavigationEast.svg';
            app.NextButton.Enable = 'off';
            app.NextButton.Visible = 'off';
            app.NextButton.Tooltip = {'Next Question'};
            app.NextButton.Text = '';
            app.ClearButton = uibutton(app.GeneralArea, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'clear.svg';
            app.ClearButton.Enable = 'off';
            app.ClearButton.Visible = 'off';
            app.ClearButton.Tooltip = {'Clear all answers'};
            app.ClearButton.Text = '';
            app.WelcomeLabel = uilabel(app.GeneralArea);
            app.WelcomeLabel.Text = {'Welcome to Gauss''s Law practice App'; ''; 'To start practicing, select a case in the menu on the top-left of the app.'};
            app.Step6Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step6CtrlLayout = uigridlayout(app.Step6Ctrl);
            app.Step6Label4 = uilabel(app.Step6CtrlLayout);
            app.Step6Label4.Interpreter = 'latex';
            app.Step6Label4.Text = '';
            app.Step6Label3 = uilabel(app.Step6CtrlLayout);
            app.Step6Label3.Interpreter = 'latex';
            app.Step6Label3.Text = '';
            app.Step6Label2 = uilabel(app.Step6CtrlLayout);
            app.Step6Label2.Interpreter = 'latex';
            app.Step6Label2.Text = '';
            app.Step6Label1 = uilabel(app.Step6CtrlLayout);
            app.Step6Label1.Interpreter = 'latex';
            app.Step6Label1.Text = '';
            app.Step6Check4 = uicheckbox(app.Step6CtrlLayout);
            app.Step6Check4.ValueChangedFcn = createCallbackFcn(app, @Step6Check4ValueChanged, true);
            app.Step6Check4.Text = '';
            app.Step6Check3 = uicheckbox(app.Step6CtrlLayout);
            app.Step6Check3.ValueChangedFcn = createCallbackFcn(app, @Step6Check3ValueChanged, true);
            app.Step6Check3.Text = '';
            app.Step6Check2 = uicheckbox(app.Step6CtrlLayout);
            app.Step6Check2.ValueChangedFcn = createCallbackFcn(app, @Step6Check2ValueChanged, true);
            app.Step6Check2.Text = '';
            app.Step6Check1 = uicheckbox(app.Step6CtrlLayout);
            app.Step6Check1.ValueChangedFcn = createCallbackFcn(app, @Step6Check1ValueChanged, true);
            app.Step6Check1.Text = '';
            app.Step5Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step5CtrlLayout = uigridlayout(app.Step5Ctrl);
            app.Step5Label4 = uilabel(app.Step5CtrlLayout);
            app.Step5Label4.Interpreter = 'latex';
            app.Step5Label4.Text = '';
            app.Step5Label3 = uilabel(app.Step5CtrlLayout);
            app.Step5Label3.Interpreter = 'latex';
            app.Step5Label3.Text = '';
            app.Step5Label2 = uilabel(app.Step5CtrlLayout);
            app.Step5Label2.Interpreter = 'latex';
            app.Step5Label2.Text = '';
            app.Step5Label1 = uilabel(app.Step5CtrlLayout);
            app.Step5Label1.Interpreter = 'latex';
            app.Step5Label1.Text = '';
            app.Step5Check4 = uicheckbox(app.Step5CtrlLayout);
            app.Step5Check4.Text = '';
            app.Step5Check3 = uicheckbox(app.Step5CtrlLayout);
            app.Step5Check3.Text = '';
            app.Step5Check2 = uicheckbox(app.Step5CtrlLayout);
            app.Step5Check2.Text = '';
            app.Step5Check1 = uicheckbox(app.Step5CtrlLayout);
            app.Step5Check1.Text = '';
            app.Step4Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step4CtrlLayout = uigridlayout(app.Step4Ctrl);
            app.Step4Answer = uieditfield(app.Step4CtrlLayout, 'text');
            app.TotalchargeLabel = uilabel(app.Step4CtrlLayout);
            app.TotalchargeLabel.Interpreter = 'latex';
            app.TotalchargeLabel.Text = 'Total charge:';
            app.Step4Sym = uilabel(app.Step4CtrlLayout);
            app.Step4Sym.Interpreter = 'latex';
            app.Step4Sym.Text = '';
            app.Step3Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step3CtrlLayout = uigridlayout(app.Step3Ctrl);
            app.SurfaceLabel = uilabel(app.Step3CtrlLayout);
            app.SurfaceLabel.Interpreter = 'latex';
            app.SurfaceLabel.Text = 'Surface:';
            app.SurfaceDropDown = uidropdown(app.Step3CtrlLayout);
            app.SurfaceDropDown.Items = {'??', 'Gaussian Sphere', 'Gaussian Cylinder', 'Gaussian Cube'};
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SurfaceDropDown.Value = '??';
            app.Step2Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step2CtrlLayout = uigridlayout(app.Step2Ctrl);
            app.Step2Label4 = uilabel(app.Step2CtrlLayout);
            app.Step2Label4.Interpreter = 'latex';
            app.Step2Label4.Text = '';
            app.Step2Label3 = uilabel(app.Step2CtrlLayout);
            app.Step2Label3.Interpreter = 'latex';
            app.Step2Label3.Text = '';
            app.Step2Label2 = uilabel(app.Step2CtrlLayout);
            app.Step2Label2.Interpreter = 'latex';
            app.Step2Label2.Text = '';
            app.Step2Label1 = uilabel(app.Step2CtrlLayout);
            app.Step2Label1.Interpreter = 'latex';
            app.Step2Label1.Text = '';
            app.Step2Check2 = uicheckbox(app.Step2CtrlLayout);
            app.Step2Check2.Text = '';
            app.Step2Check3 = uicheckbox(app.Step2CtrlLayout);
            app.Step2Check3.Text = '';
            app.Step2Check4 = uicheckbox(app.Step2CtrlLayout);
            app.Step2Check4.Text = '';
            app.Step2Check1 = uicheckbox(app.Step2CtrlLayout);
            app.Step2Check1.Text = '';
            app.Step1Ctrl = uipanel(app.GaussLabUIFigure);
            app.Step1CtrlLayout = uigridlayout(app.Step1Ctrl);
            app.Step1Label4 = uilabel(app.Step1CtrlLayout);
            app.Step1Label4.Interpreter = 'latex';
            app.Step1Label4.Text = 'Spherical Symmetry';
            app.Step1Label3 = uilabel(app.Step1CtrlLayout);
            app.Step1Label3.Interpreter = 'latex';
            app.Step1Label3.Text = 'Cylindrical Symmetry';
            app.Step1Label2 = uilabel(app.Step1CtrlLayout);
            app.Step1Label2.Interpreter = 'latex';
            app.Step1Label2.Text = 'Planar Symmetry';
            app.Step1Label1 = uilabel(app.Step1CtrlLayout);
            app.Step1Label1.Interpreter = 'latex';
            app.Step1Label1.Text = 'None';
            app.CheckBoxNone = uicheckbox(app.Step1CtrlLayout);
            app.CheckBoxNone.Text = '';
            app.CheckBoxPlanar = uicheckbox(app.Step1CtrlLayout);
            app.CheckBoxPlanar.Text = '';
            app.CheckBoxCylindrical = uicheckbox(app.Step1CtrlLayout);
            app.CheckBoxCylindrical.Text = '';
            app.CheckBoxSpherical = uicheckbox(app.Step1CtrlLayout);
            app.CheckBoxSpherical.Text = '';
            app.Step6Quest = uipanel(app.GaussLabUIFigure);
            app.Step6Quest.Title = 'Step 6: Compute the E';
            app.Step6QuestLayout = uigridlayout(app.Step6Quest);
            app.Step6Label = uilabel(app.Step6QuestLayout);
            app.Step6Label.Interpreter = 'latex';
            app.Step6Label.Text = 'Based on all your previous answers, what is the correct expression?';
            app.Step5Quest = uipanel(app.GaussLabUIFigure);
            app.Step5Quest.Title = 'Step 5: Break Down Integral';
            app.Step5QuestLayout = uigridlayout(app.Step5Quest);
            app.Step5Label = uilabel(app.Step5QuestLayout);
            app.Step5Label.Interpreter = 'latex';
            app.Step5Label.Text = '';
            app.Step4Quest = uipanel(app.GaussLabUIFigure);
            app.Step4Quest.Title = 'Step 4: Count Charge inside the Surface';
            app.Step4QuestLayout = uigridlayout(app.Step4Quest);
            app.Step4Label1 = uilabel(app.Step4QuestLayout);
            app.Step4Label1.Interpreter = 'latex';
            app.Step4Label1.Text = 'Based on the geometrical feature of the charge distribution, compute the total charge enclosed in the Gauss surface.';
            app.Step3Quest = uipanel(app.GaussLabUIFigure);
            app.Step3Quest.Title = 'Step 3: Choose a Gauss surface';
            app.Step3QuestLayout = uigridlayout(app.Step3Quest);
            app.Step3Label = uilabel(app.Step3QuestLayout);
            app.Step3Label.Interpreter = 'latex';
            app.Step3Label.Text = 'Based on the symmetry you identified, choose the most appropriate Gaussian surface for each of the following electrostatic problems. Select from the following options:';
            app.Step2Quest = uipanel(app.GaussLabUIFigure);
            app.Step2Quest.Title = 'Step 2: Simplify the Problem';
            app.Step2QuestLayout = uigridlayout(app.Step2Quest);
            app.Step2Question = uilabel(app.Step2QuestLayout);
            app.Step2Question.Interpreter = 'latex';
            app.Step2Question.Text = {'Complete the sentence by selecting all that apply.\'; '\'; '\bf{The electric field...}'};
            app.Step1Quest = uipanel(app.GaussLabUIFigure);
            app.Step1Quest.Title = 'Step 1: Identifying Symmetry';
            app.Step1QuestLayout = uigridlayout(app.Step1Quest);
            app.Step1Label = uilabel(app.Step1QuestLayout);
            app.Step1Label.Interpreter = 'latex';
            app.Step1Label.Text = 'Identify the type of symmetry present in the problem.';
            % Show the figure after all components are created
            app.GaussLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussLaw(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GaussLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GaussLabUIFigure)
        end
    end
end